classdef demente < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Menu            matlab.ui.container.Menu
        Menu2           matlab.ui.container.Menu
        MainLayout      matlab.ui.container.GridLayout
        LabelContainer  LabelContainer
        AuxLayout       matlab.ui.container.GridLayout
        AuxImage1       matlab.ui.control.UIAxes
        AuxImage2       matlab.ui.control.UIAxes
        CentralLayout   matlab.ui.container.GridLayout
        ImageLayout     matlab.ui.container.GridLayout
        mouseInfoLabel  matlab.ui.control.Label
        MainImage       matlab.ui.control.UIAxes
        ButtonLayout    matlab.ui.container.GridLayout
        nextButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        currentImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Fill figure with axes and remove tick labels
            app.MainImage.Position = [0 0 app.UIFigure.Position(3:4)];
            app.MainImage.XLabel.String = '';
            app.MainImage.YLabel.String = '';

            axtoolbar(app.AuxImage1,{"pan","zoomin","zoomout",'restoreview'});
            axtoolbar(app.AuxImage2,{"pan","zoomin","zoomout",'restoreview'});
            axtoolbar(app.MainImage,{"pan","zoomin","zoomout",'restoreview'});


            showImage(app, app.AuxImage1, 'pig.jpg');
            showImage(app, app.AuxImage2, 'pig.jpg');
            showImage(app, app.MainImage, 'pig.jpg');

            app.mouseInfoLabel.Text = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)',0,0,0,0,0);

        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            disp("NEEEEEEXT: NON FA UN CAZO")
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            [x,y,rgb] = getMouseXY(app, app.MainImage);
            
            if x == x % is not nan
                row = ceil(y);
                col = ceil(x);
                app.mouseInfoLabel.Text = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)', row, col, rgb(1), rgb(2), rgb(3));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.MainLayout = uigridlayout(app.UIFigure);
            app.CentralLayout = uigridlayout(app.MainLayout);
            app.ButtonLayout = uigridlayout(app.CentralLayout);
            app.nextButton = uibutton(app.ButtonLayout, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = '>';
            app.ImageLayout = uigridlayout(app.CentralLayout);
            app.MainImage = uiaxes(app.ImageLayout);
            zlabel(app.MainImage, 'Z')
            app.MainImage.XTick = [];
            app.MainImage.YTick = [];
            app.mouseInfoLabel = uilabel(app.ImageLayout);
            app.AuxLayout = uigridlayout(app.MainLayout);
            app.AuxImage2 = uiaxes(app.AuxLayout);
            zlabel(app.AuxImage2, 'Z')
            app.AuxImage2.XTick = [];
            app.AuxImage2.YTick = [];
            app.AuxImage1 = uiaxes(app.AuxLayout);
            zlabel(app.AuxImage1, 'Z')
            app.AuxImage1.XTick = [];
            app.AuxImage1.YTick = [];
            app.LabelContainer = LabelContainer(app.MainLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demente

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end